classdef Springifier < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpringifierUIFigure            matlab.ui.Figure
        SpringifierTwistyEditionLabel  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        SpringInvestigatorTab          matlab.ui.container.Tab
        MaterialPropertiesPanel        matlab.ui.container.Panel
        ELabel                         matlab.ui.control.Label
        Youngs_Modulus                 matlab.ui.control.NumericEditField
        GLabel                         matlab.ui.control.Label
        Shear_Modulus                  matlab.ui.control.NumericEditField
        nuLabel                        matlab.ui.control.Label
        Poissons_Ratio                 matlab.ui.control.NumericEditField
        EngineeringDataPanel           matlab.ui.container.Panel
        ActiveHeightH0Label            matlab.ui.control.Label
        Active_Height_H0               matlab.ui.control.NumericEditField
        ActiveCoilsLabel               matlab.ui.control.Label
        Active_Coils                   matlab.ui.control.NumericEditField
        MeanDiameterLabel              matlab.ui.control.Label
        Mean_Diameter                  matlab.ui.control.NumericEditField
        SolidHeightLabel               matlab.ui.control.Label
        Solid_Height                   matlab.ui.control.NumericEditField
        StiffnessLabel                 matlab.ui.control.Label
        Stiffness                      matlab.ui.control.NumericEditField
        TwistAngleLabel                matlab.ui.control.Label
        Twist_Angle                    matlab.ui.control.NumericEditField
        ForceLabel                     matlab.ui.control.Label
        Force                          matlab.ui.control.NumericEditField
        DeflectionLabel                matlab.ui.control.Label
        Deflection                     matlab.ui.control.NumericEditField
        MaxDiameterLabel               matlab.ui.control.Label
        Max_Diameter                   matlab.ui.control.NumericEditField
        LoadedCoilsLabel               matlab.ui.control.Label
        Loaded_Coils                   matlab.ui.control.NumericEditField
        ActiveHeightH1Label            matlab.ui.control.Label
        Active_Height_H1               matlab.ui.control.NumericEditField
        PitchLabel                     matlab.ui.control.Label
        Pitch                          matlab.ui.control.NumericEditField
        PitchAngleLabel                matlab.ui.control.Label
        Number                         matlab.ui.control.NumericEditField
        ManufacturingDataPanel         matlab.ui.container.Panel
        FreeLengthLabel                matlab.ui.control.Label
        Free_Length                    matlab.ui.control.NumericEditField
        NumberCoilsLabel               matlab.ui.control.Label
        Number_Coils                   matlab.ui.control.NumericEditField
        WireDiameterLabel              matlab.ui.control.Label
        Wire_Diameter                  matlab.ui.control.NumericEditField
        EndConditionDropDownLabel      matlab.ui.control.Label
        End_Condition                  matlab.ui.control.DropDown
        InnerDiameterLabel             matlab.ui.control.Label
        Inner_Diameter                 matlab.ui.control.NumericEditField
        OuterDiameterLabel             matlab.ui.control.Label
        Outer_Diameter                 matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
        Image                          matlab.ui.control.Image
        UpdatePlotLabel                matlab.ui.control.Label
        XAxisPanel                     matlab.ui.container.Panel
        XAxisButtonGroup               matlab.ui.container.ButtonGroup
        MeanDiameterButton_X           matlab.ui.control.ToggleButton
        WireDiameterButton_X           matlab.ui.control.ToggleButton
        NumberCoilsButton_X            matlab.ui.control.ToggleButton
        FreeLengthButton_X             matlab.ui.control.ToggleButton
        DeflectionButton_X             matlab.ui.control.ToggleButton
        PitchButton_X                  matlab.ui.control.ToggleButton
        AxisLowerBoundSliderLabel      matlab.ui.control.Label
        XAxisLBSlider                  matlab.ui.control.Slider
        AxisUpperBoundSliderLabel      matlab.ui.control.Label
        XAxisUBSlider                  matlab.ui.control.Slider
        XAxisPanel_2                   matlab.ui.container.Panel
        AxisLowerBoundSlider_2Label    matlab.ui.control.Label
        YAxisLBSlider                  matlab.ui.control.Slider
        AxisUpperBoundSlider_2Label    matlab.ui.control.Label
        YAxisUBSlider                  matlab.ui.control.Slider
        YAxisButtonGroup               matlab.ui.container.ButtonGroup
        TwistAngleButton_Y             matlab.ui.control.ToggleButton
        MaximumDiameterButton_Y        matlab.ui.control.ToggleButton
        ActiveCoilsButton_Y            matlab.ui.control.ToggleButton
        SolidHeightButton_Y            matlab.ui.control.ToggleButton
        ForceButton_Y                  matlab.ui.control.ToggleButton
        StiffnessButton_Y              matlab.ui.control.ToggleButton
        LegendPanel                    matlab.ui.container.Panel
        AxisLowerBoundSlider_3Label    matlab.ui.control.Label
        LegendLBSlider                 matlab.ui.control.Slider
        AxisUpperBoundSlider_3Label    matlab.ui.control.Label
        LegendUBSlider                 matlab.ui.control.Slider
        LegendButtonGroup              matlab.ui.container.ButtonGroup
        NumberCoilsButton_L            matlab.ui.control.ToggleButton
        WireDiameterButton_L           matlab.ui.control.ToggleButton
        MeanDiameterButton_L           matlab.ui.control.ToggleButton
        NoneButton_L                   matlab.ui.control.ToggleButton
        SpringDesignerTab              matlab.ui.container.Tab
        ComingSoontoComputersNearYouLabel  matlab.ui.control.Label
        OptimizerTabLabel              matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
    end

    
    methods (Access = private)
        
        function Update_Legend_Options(app)
            selected_Y     = app.YAxisButtonGroup.SelectedObject;
            selected_X     = app.XAxisButtonGroup.SelectedObject;
            
            switch selected_X
                case  app.MeanDiameterButton_X
                    if selected_Y == app.TwistAngleButton_Y
                        app.NumberCoilsButton_L.Enable = true;
                        app.WireDiameterButton_L.Enable = false;
                        app.MeanDiameterButton_L.Enable = false;
                    elseif selected_Y == app.StiffnessButton_Y
                        app.NumberCoilsButton_L.Enable = false;
                        app.WireDiameterButton_L.Enable = true;
                        app.MeanDiameterButton_L.Enable = false;
                    end
                    
                case  app.WireDiameterButton_X
                    if selected_Y == app.SolidHeightButton_Y
                        app.NumberCoilsButton_L.Enable = true;
                        app.WireDiameterButton_L.Enable = false;
                        app.MeanDiameterButton_L.Enable = false;
                    elseif selected_Y == app.StiffnessButton_Y
                        app.NumberCoilsButton_L.Enable = true;
                        app.WireDiameterButton_L.Enable = false;
                        app.MeanDiameterButton_L.Enable = true;
                    end
                    
                case  app.NumberCoilsButton_X
                    if selected_Y == app.TwistAngleButton_Y
                        app.NumberCoilsButton_L.Enable = false;
                        app.WireDiameterButton_L.Enable = false;
                        app.MeanDiameterButton_L.Enable = true;
                    elseif selected_Y == app.MaximumDiameterButton_Y
                        app.NumberCoilsButton_L.Enable = false;
                        app.WireDiameterButton_L.Enable = false;
                        app.MeanDiameterButton_L.Enable = false;
                    elseif selected_Y == app.SolidHeightButton_Y
                        app.NumberCoilsButton_L.Enable = false;
                        app.WireDiameterButton_L.Enable = true;
                        app.MeanDiameterButton_L.Enable = false;
                    elseif selected_Y == app.StiffnessButton_Y
                        app.NumberCoilsButton_L.Enable = false;
                        app.WireDiameterButton_L.Enable = true;
                        app.MeanDiameterButton_L.Enable = true;
                    end
                    
                case  app.FreeLengthButton_X
                    if selected_Y == app.TwistAngleButton_Y
                        app.NumberCoilsButton_L.Enable = true;
                        app.WireDiameterButton_L.Enable = false;
                        app.MeanDiameterButton_L.Enable = true;
                    elseif selected_Y == app.MaximumDiameterButton_Y
                        app.NumberCoilsButton_L.Enable = true;
                        app.WireDiameterButton_L.Enable = false;
                        app.MeanDiameterButton_L.Enable = false;
                    elseif selected_Y == app.SolidHeightButton_Y
                        app.NumberCoilsButton_L.Enable = true;
                        app.WireDiameterButton_L.Enable = true;
                        app.MeanDiameterButton_L.Enable = false;
                    elseif selected_Y == app.StiffnessButton_Y
                        app.NumberCoilsButton_L.Enable = true;
                        app.WireDiameterButton_L.Enable = true;
                        app.MeanDiameterButton_L.Enable = true;
                    end
                    
                case  app.DeflectionButton_X
                    if selected_Y == app.TwistAngleButton_Y
                        app.NumberCoilsButton_L.Enable = true;
                        app.WireDiameterButton_L.Enable = false;
                        app.MeanDiameterButton_L.Enable = true;
                    elseif selected_Y == app.MaximumDiameterButton_Y
                        app.NumberCoilsButton_L.Enable = true;
                        app.WireDiameterButton_L.Enable = false;
                        app.MeanDiameterButton_L.Enable = false;
                    elseif selected_Y == app.ActiveCoilsButton_Y
                        app.NumberCoilsButton_L.Enable = true;
                        app.WireDiameterButton_L.Enable = false;
                        app.MeanDiameterButton_L.Enable = true;
                    elseif selected_Y == app.ForceButton_Y
                        app.NumberCoilsButton_L.Enable = true;
                        app.WireDiameterButton_L.Enable = true;
                        app.MeanDiameterButton_L.Enable = true;
                    elseif selected_Y == app.StiffnessButton_Y
                        app.NumberCoilsButton_L.Enable = true;
                        app.WireDiameterButton_L.Enable = true;
                        app.MeanDiameterButton_L.Enable = true;
                    end
                    
                case  app.PitchButton_X
                    if selected_Y == app.TwistAngleButton_Y
                        app.NumberCoilsButton_L.Enable = false;
                        app.WireDiameterButton_L.Enable = false;
                        app.MeanDiameterButton_L.Enable = false;
                    elseif selected_Y == app.MaximumDiameterButton_Y
                        app.NumberCoilsButton_L.Enable = false;
                        app.WireDiameterButton_L.Enable = false;
                        app.MeanDiameterButton_L.Enable = false;
                    elseif selected_Y == app.SolidHeightButton_Y
                        app.NumberCoilsButton_L.Enable = false;
                        app.WireDiameterButton_L.Enable = true;
                        app.MeanDiameterButton_L.Enable = false;
                    elseif selected_Y == app.StiffnessButton_Y
                        app.NumberCoilsButton_L.Enable = false;
                        app.WireDiameterButton_L.Enable = true;
                        app.MeanDiameterButton_L.Enable = true;
                    end
                    
            end
            
            app.LegendButtonGroup.SelectedObject = app.NoneButton_L;
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function Update_Plot(app)
            ax = app.UIAxes;
            
            % clear the plot
            cla(ax, 'reset');
            
            % get current plot combination
            selected_X = app.XAxisButtonGroup.SelectedObject;
            selected_Y = app.YAxisButtonGroup.SelectedObject;
            selected_L = app.LegendButtonGroup.SelectedObject;
            
            switch selected_X
                case  app.MeanDiameterButton_X
                    if selected_Y == app.TwistAngleButton_Y
                        n_0 = app.Get_Unloaded_Active_Coils();
                        H_0 = app.Active_Height_H0.Value;
                        delta = app.Deflection.Value;
                        H_1 = H_0 - delta;
                        nu = app.Poissons_Ratio.Value;
                        
                        % mean diameter of the spring
                        D = linspace(0.005, 0.025, 1000);
                                                
                        % No additional parameters to plot
                        if selected_L == app.NoneButton_L
                            theta = zeros(1000,1);
                            L = sqrt((pi * D * n_0).^2 + (H_0)^2);
                            R_0 = D/2;
                            
                            for i = 1:1000
                                theta(i) = compute_theta_implicit(n_0, L(i), H_0, H_1, R_0(i), nu);
                            end
                            plot(ax, D, theta*180/pi, 'LineWidth', 3)
                            title(ax, 'Twist Angle vs Mean Diameter', 'FontSize', 18)
                            xlabel(ax, 'Mean Diameter, m', 'FontSize', 16)
                            ylabel(ax, 'Twist Angle, degree', 'FontSize', 16)
                            grid(ax, 'on')
                        else
                            n_0 = [4.5, 8.5, 12.5, 16.5, 20.5];
                            R_0 = D/2;
                            theta = zeros(1000,numel(n_0));
                            
                            for j = 1:numel(n_0)
                                L = sqrt((pi * D * n_0(j)).^2 + (H_0)^2);
                                                            
                                for i = 1:1000
                                    theta(i,j) = compute_theta_implicit(n_0(j), L(i), H_0, H_1, R_0(i), nu);
                                end
                                plot(ax, D, theta(:,j)*180/pi, 'LineWidth', 3)
                                hold(ax,'on')
                                l{j} = strcat('n = ', num2str(n_0(j)));
                            end
                            
                            title(ax, 'Twist Angle vs Mean Diameter', 'FontSize', 18)
                            xlabel(ax, 'Mean Diameter, m', 'FontSize', 16)
                            ylabel(ax, 'Twist Angle, degree', 'FontSize', 16)
                            legend(ax, l, 'FontSize', 16)
                            grid(ax, 'on')
                        end
                    else
                        title(ax,'Coming Soon')
                    end


                case  app.WireDiameterButton_X
                    %plot(ax)
                    title(ax,'Coming Soon')
                    
                case  app.NumberCoilsButton_X
                    %plot(ax)
                    title(ax,'Coming Soon')
                    
                case  app.FreeLengthButton_X
                    %plot(ax)
                    title(ax,'Coming Soon')
                    
                case  app.DeflectionButton_X
                    %plot(ax)
                    title(ax,'Coming Soon')
                    
                case  app.PitchButton_X
                    %plot(ax)
                    title(ax,'Coming Soon')
                    
            end
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function n_0 = Get_Unloaded_Active_Coils(app)
            value = app.End_Condition.Value;
            if strcmp(value, 'Open')
                n_0 = app.Number_Coils.Value;
            elseif strcmp(value, 'Closed')
                n_0 = app.Number_Coils.Value - 2;
            else
                n_0 = app.Number_Coils.Value - 1.5;
            end
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function Update_Twist_Angle(app)
            n_0   = app.Get_Unloaded_Active_Coils();
            H_0   = app.Active_Height_H0.Value;
            delta = app.Deflection.Value;
            D     = app.Mean_Diameter.Value;
            nu    = app.Poissons_Ratio.Value;
            
            % make sure all the values are populated
            if n_0 * H_0 * delta * D * nu > 0
                H_1 = H_0 - delta;
                L = sqrt((pi * D * n_0).^2 + (H_0)^2);
                R_0 = D/2;
                theta = compute_theta_implicit(n_0, L, H_0, H_1, R_0, nu);
                app.Twist_Angle.Value = theta * 180 / pi;
            else
                app.Twist_Angle.Value = 0;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function Update_Free_Length(app, event)
            value = app.Free_Length.Value;  
        end

        % Selection changed function: XAxisButtonGroup
        function XAxisButtonGroupSelectionChanged(app, event)

            selectedButton = app.XAxisButtonGroup.SelectedObject;
            switch selectedButton
                case  app.MeanDiameterButton_X
                    app.TwistAngleButton_Y.Enable = true;
                    app.MaximumDiameterButton_Y.Enable = false;
                    app.ActiveCoilsButton_Y.Enable = false;
                    app.SolidHeightButton_Y.Enable = false;
                    app.ForceButton_Y.Enable = false;
                    app.StiffnessButton_Y.Enable = true;
                    
                    % make sure a valid option is selected
                    if  strcmp(app.YAxisButtonGroup.SelectedObject.Enable, 'off')
                        app.YAxisButtonGroup.SelectedObject = app.TwistAngleButton_Y;
                    end
                    
                case  app.WireDiameterButton_X
                    app.TwistAngleButton_Y.Enable = false;
                    app.MaximumDiameterButton_Y.Enable = false;
                    app.ActiveCoilsButton_Y.Enable = false;
                    app.SolidHeightButton_Y.Enable = true;
                    app.ForceButton_Y.Enable = false;
                    app.StiffnessButton_Y.Enable = true;
                    
                    % make sure a valid option is selected
                    if  strcmp(app.YAxisButtonGroup.SelectedObject.Enable, 'off')
                        app.YAxisButtonGroup.SelectedObject = app.SolidHeightButton_Y;
                    end
                    
                case  app.NumberCoilsButton_X
                    app.TwistAngleButton_Y.Enable = true;
                    app.MaximumDiameterButton_Y.Enable = true;
                    app.ActiveCoilsButton_Y.Enable = false;
                    app.SolidHeightButton_Y.Enable = true;
                    app.ForceButton_Y.Enable = false;
                    app.StiffnessButton_Y.Enable = true;
                    
                    % make sure a valid option is selected
                    if  strcmp(app.YAxisButtonGroup.SelectedObject.Enable, 'off')
                        app.YAxisButtonGroup.SelectedObject = app.TwistAngleButton_Y;
                    end
                    
                case  app.FreeLengthButton_X
                    app.TwistAngleButton_Y.Enable = true;
                    app.MaximumDiameterButton_Y.Enable = true;
                    app.ActiveCoilsButton_Y.Enable = false;
                    app.SolidHeightButton_Y.Enable = true;
                    app.ForceButton_Y.Enable = false;
                    app.StiffnessButton_Y.Enable = true;
                    
                    % make sure a valid option is selected
                    if  strcmp(app.YAxisButtonGroup.SelectedObject.Enable, 'off')
                        app.YAxisButtonGroup.SelectedObject = app.TwistAngleButton_Y;
                    end
                    
                case  app.DeflectionButton_X
                    app.TwistAngleButton_Y.Enable = true;
                    app.MaximumDiameterButton_Y.Enable = true;
                    app.ActiveCoilsButton_Y.Enable = true;
                    app.SolidHeightButton_Y.Enable = false;
                    app.ForceButton_Y.Enable = true;
                    app.StiffnessButton_Y.Enable = true;
                    
                    % make sure a valid option is selected
                    if  strcmp(app.YAxisButtonGroup.SelectedObject.Enable, 'off')
                        app.YAxisButtonGroup.SelectedObject = app.TwistAngleButton_Y;
                    end
                    
                case  app.PitchButton_X
                    app.TwistAngleButton_Y.Enable = true;
                    app.MaximumDiameterButton_Y.Enable = true;
                    app.ActiveCoilsButton_Y.Enable = false;
                    app.SolidHeightButton_Y.Enable = true;
                    app.ForceButton_Y.Enable = false;
                    app.StiffnessButton_Y.Enable = true;
                    
                    % make sure a valid option is selected
                    if  strcmp(app.YAxisButtonGroup.SelectedObject.Enable, 'off')
                        app.YAxisButtonGroup.SelectedObject = app.TwistAngleButton_Y;
                    end
            end
            
            app.Update_Legend_Options();
        end

        % Selection changed function: YAxisButtonGroup
        function YAxisButtonGroupSelectionChanged(app, event)
            app.Update_Legend_Options();
        end

        % Value changed function: End_Condition
        function End_ConditionValueChanged(app, event)
            value = app.End_Condition.Value;
            if strcmp(value, 'Open')
                app.Active_Height_H0.Value = app.Free_Length.Value;
                if app.Deflection.Value == 0
                    app.Active_Coils.Value = app.Number_Coils.Value;
                end
            elseif strcmp(value, 'Closed')
                if app.Number_Coils.Value > 2 && app.Deflection.Value == 0
                    app.Active_Coils.Value = app.Number_Coils.Value - 2;
                end
            else
                if app.Number_Coils.Value > 2 && app.Deflection.Value == 0
                    app.Active_Coils.Value = app.Number_Coils.Value - 1.5;
                end
            end
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            app.Update_Plot();
        end

        % Value changed function: Active_Height_H0
        function Active_Height_H0ValueChanged(app, event)
            value = app.Active_Height_H0.Value;
            app.Update_Twist_Angle();
        end

        % Value changed function: Mean_Diameter
        function Mean_DiameterValueChanged(app, event)
            value = app.Mean_Diameter.Value;
            app.Update_Twist_Angle();
        end

        % Value changed function: Deflection
        function DeflectionValueChanged(app, event)
            value = app.Deflection.Value;
            app.Update_Twist_Angle();
        end

        % Value changed function: Shear_Modulus
        function Shear_ModulusValueChanged(app, event)
            value = app.Shear_Modulus.Value;
            app.Update_Twist_Angle();
        end

        % Value changed function: Youngs_Modulus
        function Youngs_ModulusValueChanged(app, event)
            value = app.Youngs_Modulus.Value;
            app.Update_Twist_Angle();
        end

        % Value changed function: Number_Coils
        function Number_CoilsValueChanged(app, event)
            value = app.Number_Coils.Value;
            app.Update_Twist_Angle()
        end

        % Value changed function: Inner_Diameter
        function Inner_DiameterValueChanged(app, event)
            value = app.Inner_Diameter.Value;
            app.Mean_Diameter.Value = value + app.Wire_Diameter.Value;
            app.Outer_Diameter.Value = value + app.Wire_Diameter.Value * 2;
        end

        % Value changed function: Outer_Diameter
        function Outer_DiameterValueChanged(app, event)
            value = app.Outer_Diameter.Value;
            if value > 0 && app.Inner_Diameter
                app.Wire_Diameter.Value = (value - app.Inner_Diameter.Value)/2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpringifierUIFigure = uifigure('Visible', 'off');
            app.SpringifierUIFigure.Name = 'Springifier';
            app.SpringifierTwistyEditionLabel = uilabel(app.SpringifierUIFigure);
            app.SpringifierTwistyEditionLabel.Text = 'Springifier - Twisty Edition';
            app.TabGroup = uitabgroup(app.SpringifierUIFigure);
            app.SpringInvestigatorTab = uitab(app.TabGroup);
            app.SpringInvestigatorTab.Title = 'Spring Investigator';
            app.MaterialPropertiesPanel = uipanel(app.SpringInvestigatorTab);
            app.MaterialPropertiesPanel.Title = 'Material Properties';
            app.ELabel = uilabel(app.MaterialPropertiesPanel);
            app.ELabel.Text = 'E';
            app.Youngs_Modulus = uieditfield(app.MaterialPropertiesPanel, 'numeric');
            app.Youngs_Modulus.Limits = [0 Inf];
            app.Youngs_Modulus.ValueChangedFcn = createCallbackFcn(app, @Youngs_ModulusValueChanged, true);
            app.Youngs_Modulus.Tooltip = {'Young''s Modulus -'; ' GPa'};
            app.Youngs_Modulus.Value = 210;
            app.GLabel = uilabel(app.MaterialPropertiesPanel);
            app.GLabel.Text = 'G';
            app.Shear_Modulus = uieditfield(app.MaterialPropertiesPanel, 'numeric');
            app.Shear_Modulus.Limits = [0 Inf];
            app.Shear_Modulus.ValueChangedFcn = createCallbackFcn(app, @Shear_ModulusValueChanged, true);
            app.Shear_Modulus.Tooltip = {'Shear Modulus - GPa'};
            app.Shear_Modulus.Value = 79;
            app.nuLabel = uilabel(app.MaterialPropertiesPanel);
            app.nuLabel.Text = 'nu';
            app.Poissons_Ratio = uieditfield(app.MaterialPropertiesPanel, 'numeric');
            app.Poissons_Ratio.Limits = [0 Inf];
            app.Poissons_Ratio.Editable = 'off';
            app.Poissons_Ratio.Tooltip = {'E/G = 2(1+nu)'};
            app.Poissons_Ratio.Value = 0.3;
            app.EngineeringDataPanel = uipanel(app.SpringInvestigatorTab);
            app.EngineeringDataPanel.Title = 'Engineering Data';
            app.ActiveHeightH0Label = uilabel(app.EngineeringDataPanel);
            app.ActiveHeightH0Label.Text = 'Active Height - H0';
            app.Active_Height_H0 = uieditfield(app.EngineeringDataPanel, 'numeric');
            app.Active_Height_H0.Limits = [0 Inf];
            app.Active_Height_H0.ValueChangedFcn = createCallbackFcn(app, @Active_Height_H0ValueChanged, true);
            app.Active_Height_H0.Tooltip = {'May depend on end conditions'};
            app.ActiveCoilsLabel = uilabel(app.EngineeringDataPanel);
            app.ActiveCoilsLabel.Text = 'Active Coils';
            app.Active_Coils = uieditfield(app.EngineeringDataPanel, 'numeric');
            app.Active_Coils.Limits = [0 Inf];
            app.Active_Coils.Editable = 'off';
            app.Active_Coils.Tooltip = {'May depend on end condition'};
            app.MeanDiameterLabel = uilabel(app.EngineeringDataPanel);
            app.MeanDiameterLabel.Text = 'Mean Diameter';
            app.Mean_Diameter = uieditfield(app.EngineeringDataPanel, 'numeric');
            app.Mean_Diameter.Limits = [0 Inf];
            app.Mean_Diameter.ValueChangedFcn = createCallbackFcn(app, @Mean_DiameterValueChanged, true);
            app.Mean_Diameter.Tooltip = {'m'};
            app.SolidHeightLabel = uilabel(app.EngineeringDataPanel);
            app.SolidHeightLabel.Text = 'Solid Height';
            app.Solid_Height = uieditfield(app.EngineeringDataPanel, 'numeric');
            app.Solid_Height.Limits = [0 Inf];
            app.Solid_Height.Tooltip = {'m'};
            app.StiffnessLabel = uilabel(app.EngineeringDataPanel);
            app.StiffnessLabel.Text = 'Stiffness';
            app.Stiffness = uieditfield(app.EngineeringDataPanel, 'numeric');
            app.Stiffness.Limits = [0 Inf];
            app.Stiffness.Editable = 'off';
            app.Stiffness.Tooltip = {'N/m - computed [roperty from geometry and material properties'};
            app.TwistAngleLabel = uilabel(app.EngineeringDataPanel);
            app.TwistAngleLabel.Text = 'Twist Angle';
            app.Twist_Angle = uieditfield(app.EngineeringDataPanel, 'numeric');
            app.Twist_Angle.Editable = 'off';
            app.Twist_Angle.Tooltip = {'Twist angle induced by loading'; ' degrees'};
            app.ForceLabel = uilabel(app.EngineeringDataPanel);
            app.ForceLabel.Text = 'Force';
            app.Force = uieditfield(app.EngineeringDataPanel, 'numeric');
            app.Force.Tooltip = {'Newtons'};
            app.DeflectionLabel = uilabel(app.EngineeringDataPanel);
            app.DeflectionLabel.Text = 'Deflection';
            app.Deflection = uieditfield(app.EngineeringDataPanel, 'numeric');
            app.Deflection.ValueChangedFcn = createCallbackFcn(app, @DeflectionValueChanged, true);
            app.Deflection.Tooltip = {'Newtons'};
            app.MaxDiameterLabel = uilabel(app.EngineeringDataPanel);
            app.MaxDiameterLabel.Text = 'Max Diameter';
            app.Max_Diameter = uieditfield(app.EngineeringDataPanel, 'numeric');
            app.Max_Diameter.Editable = 'off';
            app.Max_Diameter.Tooltip = {'Maximum diamter under load'};
            app.LoadedCoilsLabel = uilabel(app.EngineeringDataPanel);
            app.LoadedCoilsLabel.Text = 'Loaded Coils';
            app.Loaded_Coils = uieditfield(app.EngineeringDataPanel, 'numeric');
            app.Loaded_Coils.Editable = 'off';
            app.Loaded_Coils.Tooltip = {'Number of active coils under load'};
            app.ActiveHeightH1Label = uilabel(app.EngineeringDataPanel);
            app.ActiveHeightH1Label.Text = 'Active Height - H1';
            app.Active_Height_H1 = uieditfield(app.EngineeringDataPanel, 'numeric');
            app.Active_Height_H1.Editable = 'off';
            app.Active_Height_H1.Tooltip = {'Number of active coils under load'};
            app.PitchLabel = uilabel(app.EngineeringDataPanel);
            app.PitchLabel.Text = 'Pitch';
            app.Pitch = uieditfield(app.EngineeringDataPanel, 'numeric');
            app.Pitch.Tooltip = {'Newtons'};
            app.PitchAngleLabel = uilabel(app.EngineeringDataPanel);
            app.PitchAngleLabel.Text = 'Pitch Angle';
            app.Number = uieditfield(app.EngineeringDataPanel, 'numeric');
            app.Number.Limits = [0 Inf];
            app.Number.Editable = 'off';
            app.ManufacturingDataPanel = uipanel(app.SpringInvestigatorTab);
            app.ManufacturingDataPanel.Title = 'Manufacturing Data';
            app.FreeLengthLabel = uilabel(app.ManufacturingDataPanel);
            app.FreeLengthLabel.Text = 'Free Length';
            app.Free_Length = uieditfield(app.ManufacturingDataPanel, 'numeric');
            app.Free_Length.Limits = [0 Inf];
            app.NumberCoilsLabel = uilabel(app.ManufacturingDataPanel);
            app.NumberCoilsLabel.Text = 'Number Coils';
            app.Number_Coils = uieditfield(app.ManufacturingDataPanel, 'numeric');
            app.Number_Coils.Limits = [0 Inf];
            app.Number_Coils.ValueChangedFcn = createCallbackFcn(app, @Number_CoilsValueChanged, true);
            app.WireDiameterLabel = uilabel(app.ManufacturingDataPanel);
            app.WireDiameterLabel.Text = 'Wire Diameter';
            app.Wire_Diameter = uieditfield(app.ManufacturingDataPanel, 'numeric');
            app.Wire_Diameter.Limits = [0 Inf];
            app.EndConditionDropDownLabel = uilabel(app.ManufacturingDataPanel);
            app.EndConditionDropDownLabel.Text = 'End Condition';
            app.End_Condition = uidropdown(app.ManufacturingDataPanel);
            app.End_Condition.Items = {'Open', 'Closed', 'Closed-Ground'};
            app.End_Condition.ValueChangedFcn = createCallbackFcn(app, @End_ConditionValueChanged, true);
            app.End_Condition.Value = 'Open';
            app.InnerDiameterLabel = uilabel(app.ManufacturingDataPanel);
            app.InnerDiameterLabel.Text = 'Inner Diameter';
            app.Inner_Diameter = uieditfield(app.ManufacturingDataPanel, 'numeric');
            app.Inner_Diameter.Limits = [0 Inf];
            app.Inner_Diameter.ValueChangedFcn = createCallbackFcn(app, @Inner_DiameterValueChanged, true);
            app.OuterDiameterLabel = uilabel(app.ManufacturingDataPanel);
            app.OuterDiameterLabel.Text = 'Outer Diameter';
            app.Outer_Diameter = uieditfield(app.ManufacturingDataPanel, 'numeric');
            app.Outer_Diameter.Limits = [0 Inf];
            app.Outer_Diameter.ValueChangedFcn = createCallbackFcn(app, @Outer_DiameterValueChanged, true);
            app.UIAxes = uiaxes(app.SpringInvestigatorTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Image = uiimage(app.SpringInvestigatorTab);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'spring.png';
            app.UpdatePlotLabel = uilabel(app.SpringInvestigatorTab);
            app.UpdatePlotLabel.Text = 'Update Plot';
            app.XAxisPanel = uipanel(app.SpringInvestigatorTab);
            app.XAxisPanel.Title = 'X-Axis';
            app.XAxisButtonGroup = uibuttongroup(app.XAxisPanel);
            app.XAxisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @XAxisButtonGroupSelectionChanged, true);
            app.XAxisButtonGroup.Tooltip = {''};
            app.XAxisButtonGroup.Title = 'X - Parameters';
            app.MeanDiameterButton_X = uitogglebutton(app.XAxisButtonGroup);
            app.MeanDiameterButton_X.Text = 'Mean Diameter';
            app.MeanDiameterButton_X.Value = true;
            app.WireDiameterButton_X = uitogglebutton(app.XAxisButtonGroup);
            app.WireDiameterButton_X.Text = 'Wire Diameter';
            app.NumberCoilsButton_X = uitogglebutton(app.XAxisButtonGroup);
            app.NumberCoilsButton_X.Text = 'Number Coils';
            app.FreeLengthButton_X = uitogglebutton(app.XAxisButtonGroup);
            app.FreeLengthButton_X.Text = 'Free Length';
            app.DeflectionButton_X = uitogglebutton(app.XAxisButtonGroup);
            app.DeflectionButton_X.Text = 'Deflection';
            app.PitchButton_X = uitogglebutton(app.XAxisButtonGroup);
            app.PitchButton_X.Text = 'Pitch';
            app.AxisLowerBoundSliderLabel = uilabel(app.XAxisPanel);
            app.AxisLowerBoundSliderLabel.Text = 'Axis Lower Bound';
            app.XAxisLBSlider = uislider(app.XAxisPanel);
            app.AxisUpperBoundSliderLabel = uilabel(app.XAxisPanel);
            app.AxisUpperBoundSliderLabel.Text = 'Axis Upper Bound';
            app.XAxisUBSlider = uislider(app.XAxisPanel);
            app.XAxisPanel_2 = uipanel(app.SpringInvestigatorTab);
            app.XAxisPanel_2.Title = 'X-Axis';
            app.AxisLowerBoundSlider_2Label = uilabel(app.XAxisPanel_2);
            app.AxisLowerBoundSlider_2Label.Text = 'Axis Lower Bound';
            app.YAxisLBSlider = uislider(app.XAxisPanel_2);
            app.AxisUpperBoundSlider_2Label = uilabel(app.XAxisPanel_2);
            app.AxisUpperBoundSlider_2Label.Text = 'Axis Upper Bound';
            app.YAxisUBSlider = uislider(app.XAxisPanel_2);
            app.YAxisButtonGroup = uibuttongroup(app.XAxisPanel_2);
            app.YAxisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @YAxisButtonGroupSelectionChanged, true);
            app.YAxisButtonGroup.Tooltip = {''};
            app.YAxisButtonGroup.Title = 'Y - Parameters';
            app.TwistAngleButton_Y = uitogglebutton(app.YAxisButtonGroup);
            app.TwistAngleButton_Y.Text = 'Twist Angle';
            app.TwistAngleButton_Y.Value = true;
            app.MaximumDiameterButton_Y = uitogglebutton(app.YAxisButtonGroup);
            app.MaximumDiameterButton_Y.Text = 'Maximum Diameter';
            app.ActiveCoilsButton_Y = uitogglebutton(app.YAxisButtonGroup);
            app.ActiveCoilsButton_Y.Text = 'Active Coils';
            app.SolidHeightButton_Y = uitogglebutton(app.YAxisButtonGroup);
            app.SolidHeightButton_Y.Text = 'Solid Height';
            app.ForceButton_Y = uitogglebutton(app.YAxisButtonGroup);
            app.ForceButton_Y.Text = 'Force';
            app.StiffnessButton_Y = uitogglebutton(app.YAxisButtonGroup);
            app.StiffnessButton_Y.Text = 'Stiffness';
            app.LegendPanel = uipanel(app.SpringInvestigatorTab);
            app.LegendPanel.Title = 'Legend';
            app.AxisLowerBoundSlider_3Label = uilabel(app.LegendPanel);
            app.AxisLowerBoundSlider_3Label.Text = 'Axis Lower Bound';
            app.LegendLBSlider = uislider(app.LegendPanel);
            app.AxisUpperBoundSlider_3Label = uilabel(app.LegendPanel);
            app.AxisUpperBoundSlider_3Label.Text = 'Axis Upper Bound';
            app.LegendUBSlider = uislider(app.LegendPanel);
            app.LegendButtonGroup = uibuttongroup(app.LegendPanel);
            app.LegendButtonGroup.Tooltip = {''};
            app.LegendButtonGroup.Title = 'Legend - Parameters';
            app.NumberCoilsButton_L = uitogglebutton(app.LegendButtonGroup);
            app.NumberCoilsButton_L.Text = 'Number Coils';
            app.NumberCoilsButton_L.Value = true;
            app.WireDiameterButton_L = uitogglebutton(app.LegendButtonGroup);
            app.WireDiameterButton_L.Text = 'Wire Diameter';
            app.MeanDiameterButton_L = uitogglebutton(app.LegendButtonGroup);
            app.MeanDiameterButton_L.Text = 'Mean Diameter';
            app.NoneButton_L = uitogglebutton(app.LegendButtonGroup);
            app.NoneButton_L.Text = 'None';
            app.SpringDesignerTab = uitab(app.TabGroup);
            app.SpringDesignerTab.Title = 'Spring Designer';
            app.ComingSoontoComputersNearYouLabel = uilabel(app.SpringDesignerTab);
            app.ComingSoontoComputersNearYouLabel.Text = 'Coming Soon to Computers Near You';
            app.OptimizerTabLabel = uilabel(app.SpringDesignerTab);
            app.OptimizerTabLabel.Text = 'Optimizer Tab';
            app.Image2 = uiimage(app.SpringDesignerTab);
            app.Image2.ImageSource = 'spring.png';
            % Show the figure after all components are created
            app.SpringifierUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Springifier

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpringifierUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpringifierUIFigure)
        end
    end
end